classdef tspDesign1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSP_APP                         matlab.ui.Figure
        DataPlotPanel                   matlab.ui.container.Panel
        VillePlot                       matlab.ui.control.UIAxes
        FitnessPlot                     matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TravelingSalesmanproblemLabel   matlab.ui.control.Label
        ParamtresPanel                  matlab.ui.container.Panel
        ProbabilitdeMuationEditFieldLabel  matlab.ui.control.Label
        PM                              matlab.ui.control.NumericEditField
        ProbabilitdeCroisementEditFieldLabel  matlab.ui.control.Label
        PC                              matlab.ui.control.NumericEditField
        NombredepopulationEditFieldLabel  matlab.ui.control.Label
        NPOP                            matlab.ui.control.NumericEditField
        NombredeVilleEditFieldLabel     matlab.ui.control.Label
        PS                              matlab.ui.control.NumericEditField
        NombrediterationEditFieldLabel  matlab.ui.control.Label
        NITER                           matlab.ui.control.NumericEditField
        OprateursPanel                  matlab.ui.container.Panel
        SlectionDropDownLabel           matlab.ui.control.Label
        SelectionOP                     matlab.ui.control.DropDown
        CroisementDropDown_2Label       matlab.ui.control.Label
        CroisementOP                    matlab.ui.control.DropDown
        MutationDropDownLabel           matlab.ui.control.Label
        MutationOP                      matlab.ui.control.DropDown
        Elitisme                        matlab.ui.control.CheckBox
        Immigration                     matlab.ui.control.CheckBox
        RsultatsnumriquesPanel          matlab.ui.container.Panel
        chemin                          matlab.ui.control.TextArea
        CheminOptimalCourrantLabel      matlab.ui.control.Label
        MeilleureDsitanceEditFieldLabel  matlab.ui.control.Label
        Distance                        matlab.ui.control.EditField
        GnrationLabel                   matlab.ui.control.Label
        Temps                           matlab.ui.control.NumericEditField
        Exit                            matlab.ui.control.Button
    end


    properties (Access = private)
        %app.ParameterPanel
        
        nbrpop = 40;        
        nbriter = 100;
        nbrville = 14;
        pm = 0.01;
        pc = 0.7;
        ps = 0.5;
       
        
        %app.OperatorPanel
        selection ;
        croisement;
        mutation;
       
    end


    methods (Access = private)
    
        function [distmat,Villes] = file_init(app)
            [FileName,~] = uigetfile('*.txt','sélectionnez le fichier texte');

          fid = fopen(FileName,'r');
          Villes = dlmread(FileName,'%d%10.4f%10.4f'); %Stocker les valeurs du fichier dans la matrice Villes
          [app.nbrville,~] = size(Villes);
          fclose(fid);
         
        distmat = zeros(app.nbrville,app.nbrville); %La matrice de Distance
        %popinit = zeros(app.nbrpop,app.nbrville);   %La matrice du population initilae
        
%         Creation du population initiale
%         for i=1:app.nbrpop
%            popinit(i,:) = randperm(app.nbrville);
%         end
        
        %Remplire la matrice de distance 
        for i=1:app.nbrville
            for j=1:app.nbrville
                distmat(i,j) = sqrt( (Villes(i,2)-Villes(j,2))^2 + (Villes(i,3)-Villes(j,3))^2 );
            end
        end

            
        end


        function [vectfit,best,minVal,indxBest]=calcFitness(app,distmat,popinit)
            vectfit = zeros(1,app.nbrpop);
                 %app.chemin.Value = num2str(popinit(10,:));
             for k=1:app.nbrpop
                tmp = popinit(k,:);
                app.chemin.Value = num2str(tmp);
                vectfit(k) = vectfit(k) + distmat(app.nbrville,tmp(1));
                for l=1:app.nbrville-1
                    
                      vectfit(k) = vectfit(k) + distmat(tmp(l),tmp(l+1));
                end
             end
             
            [minVal,indxBest]=min(vectfit) ;
            best=popinit(indxBest , : ) ;
        end


        function matselec = rouletteSelect(app,vectfit,popinit)
            
         %[nbrpop,nbrville] = size(popinit);
         nbrpopS = floor(app.nbrpop*app.ps);    
         matselec = zeros(nbrpopS,app.nbrville);
        
         s = 0;
         cumProb = zeros(1,length(vectfit));       
         
          %calcule de probabilité cumulée
         for j=1:length(vectfit)
             cumProb(j) = s + vectfit(j);
             s = cumProb(j);
         end
          
         % Remplir la matrice de selecetion
         for i=1:nbrpopS
         r = (max(cumProb)-min(cumProb))*rand() + min(cumProb);
         for j=1:length(cumProb)
            if r<=cumProb(j)
                matselec(i,:) = popinit(j,:);
                break;
            end
         end
         end
            
        end


        function popsuiv = croisementOX(app,matselec,popinit)
        %[nbrpop,nbrville] = size(popinit);
[ligne,~] = size(matselec);
 %p_x = 0.55;
popsuiv = zeros(ligne,app.nbrville); %population des enfants

k = 1;
while sum(popsuiv(end,:)) == 0
%for k=1:2:ligne % 50% individus va nous donner 50% 

r1 = randi([1 ligne]); %on choisi aléatoirement
r2 = randi([1 ligne]); %deux parents du pop_init

parent1 = matselec(r1,:);
parent2 = matselec(r2,:);

    if rand <= app.pc

    [child1,child2] = CRS_OX(app,parent1,parent2); %fonction d'aide ci-dessus
    popsuiv(k,:) = child1;
    popsuiv(k+1,:) = child2;
    k = k + 2;
    
    end
end
        end


   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
function [ch1,ch2] = CRS_OX(app,parent1,parent2)

    nVar=length(parent1);
    
    c=randsample(nVar-1,2); % Retourner deux point de courpures
    point1=min(c);
    point2=max(c);
    
    ch1=parent1(point1+1:point2);
    [~,loc]=ismember(ch1,parent2);% ex: ch1=(1 3),parent2=(2 3 1 4 5 6). Donc loc=(3 2)
    loc=sort(loc);
    ch1=[parent1(1:point1) parent2(loc) parent1(point2+1:end)];
    
        
    ch2=parent2(point1+1:point2);
    [~,loc1]=ismember(ch2,parent1);
    loc1=sort(loc1);
    ch2=[parent2(1:point1) parent1(loc1) parent2(point2+1:end)];  
end


        function popsuivm = mutation_PSM(app,popsuiv)
           % p_mut = 0.02;  % la probalité de mutation
[ligne,~] = size(popsuiv);

for i = 1 : ligne
    child = popsuiv(i, :);
    len = length(child);
        for j = 1 : len
            if rand <= app.pm
                prev = child(j);
                index = randi(len);
                child(j) = child(index);
                child(index) = prev;
                popsuiv(i, :) = child;
            end
        end
end
popsuivm = popsuiv;
        end


        function popfinal = insertion(app,popsuivm,popinit,vectfit)
            [ligne,~] = size(popsuivm);
            [ligneP,~] = size(popinit);

            popfinal = zeros(ligneP,app.nbrville);
            popfinal(1:ligne,:) = popsuivm;
            fit = sort(vectfit);

            for i= ligne+1 : ligneP-1
            r = randi([1 ligneP]);
            popfinal(i,:) = popinit(r,:);
            end
   
            % ELitisme
            index = find(vectfit == fit(1));
            popfinal(end,:) = popinit(index(1),:);
            
        end
    end


    methods (Access = private)

        % Value changed function: CroisementOP
        function CroisementOPValueChanged(app, event)
            app.croisement = app.CroisementOP.Value;
            
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            closereq
        end

        % Value changed function: MutationOP
        function MutationOPValueChanged(app, event)
            app.mutation = app.MutationOP.Value;
            
        end

        % Value changed function: NITER
        function NITERValueChanged(app, event)
            app.nbriter = app.NITER.Value;
        end

        % Value changed function: NPOP
        function NPOPValueChanged(app, event)
            app.nbrpop = app.NPOP.Value;
            
        end

        % Value changed function: PC
        function PCValueChanged(app, event)
            app.pc = app.PC.Value;
            
        end

        % Value changed function: PM
        function PMValueChanged(app, event)
            app.pm = app.PM.Value;
        end

        % Value changed function: PS
        function PSValueChanged(app, event)
            app.ps = app.PS.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(findobj(app.VillePlot,'type','line'));
            delete(findobj(app.FitnessPlot,'type','line'));
            app.PM.Value = 0;
            app.PC.Value = 0;
            app.PS.Value = 0;
            app.NPOP.Value = 0;
            app.NITER.Value = 0;
            
            app.chemin.Value = '';
            app.Distance.Value = ''; %
            app.Temps.Value = 0; % génération
            
        end

        % Value changed function: SelectionOP
        function SelectionOPValueChanged(app, event)
            app.selection = app.SelectionOP.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
               
             popinit = zeros(app.nbrpop,app.nbrville);
          %Creation du population initiale
        for i=1:app.nbrpop
           popinit(i,:) = randperm(app.nbrville);
        end  
        
            [distmat,Villes] = file_init(app);       
               
            
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            x = Villes(:,2)'; % x cooronate
            y = Villes(:,3)'; % y cooronate
            
            GenerationT = zeros(1,app.nbriter);     % meilleure valeur pour chaque iteration
            GenerationBest = zeros(app.nbriter,app.nbrville); % meilleur individu pour chaque iteration
            %popinit = zeros(app.nbrpop,app.nbrville);
           
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        for gen=1:app.nbriter % !!!!!!!!!!!!!!!!!!!!!!!!!!!!
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
                                 %% Evalutaion %%
          
            [vectfit,best,minVal,indxBest]=calcFitness(app,distmat,popinit);
            GenerationT(gen) = minVal;
            GenerationBest(gen,:) = best;
            
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       %% Selection pour la reproduction %%
                       
             matselec = rouletteSelect(app,vectfit,popinit);
     
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
            %% Croisement sur population selectionnée
           popsuiv = croisementOX(app,matselec,popinit);
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
            %% Mutation : Permuter deux villes aleatoires
           
            popsuivm = mutation_PSM(app,popsuiv);
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
            %% Insertion
            popfinal = insertion(app,popsuivm,popinit,vectfit);
            popinit = popfinal;
            
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% AFfichage 
            app.chemin.Value = num2str(best);
            app.Distance.Value = num2str(minVal);
            app.Temps.Value  =  gen;       
            
                                %%    PLOT    %%
                                
             plot(app.VillePlot,[x(GenerationBest(gen,:)) x(GenerationBest(gen,1))],[y(GenerationBest(gen,:)) y(GenerationBest(gen,1))],x,y,'.','LineWidth',1.5);
             for p=1:app.nbrville
            x1 = Villes(p,2);
             y1 = Villes(p,3);
             txt1 = num2str(p);
             text(app.VillePlot,x1,y1,txt1);
             end
            drawnow;
       end
           
         iters=1:app.nbriter;
            
            plot(app.FitnessPlot,iters,GenerationT,'LineWidth',1.5);
            %xlabel(app.FitnessPlot,'Generation');
            %ylabel(app.FitnessPlot,'Fitness');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TSP_APP = uifigure;
            app.TSP_APP.Name = 'UI Figure';
            app.DataPlotPanel = uipanel(app.TSP_APP);
            app.DataPlotPanel.Title = 'Data Plot';
            app.VillePlot = uiaxes(app.DataPlotPanel);
            title(app.VillePlot, 'Burma14')
            app.FitnessPlot = uiaxes(app.DataPlotPanel);
            xlabel(app.FitnessPlot, 'Generation')
            ylabel(app.FitnessPlot, 'Fitness')
            app.StartButton = uibutton(app.TSP_APP, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.TSP_APP, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TravelingSalesmanproblemLabel = uilabel(app.TSP_APP);
            app.TravelingSalesmanproblemLabel.Text = 'Traveling Salesman problem';
            app.ParamtresPanel = uipanel(app.TSP_APP);
            app.ParamtresPanel.Title = 'Paramétres';
            app.ProbabilitdeMuationEditFieldLabel = uilabel(app.ParamtresPanel);
            app.ProbabilitdeMuationEditFieldLabel.Text = 'Probabilité de Muation';
            app.PM = uieditfield(app.ParamtresPanel, 'numeric');
            app.PM.ValueChangedFcn = createCallbackFcn(app, @PMValueChanged, true);
            app.PM.Limits = [0 1];
            app.ProbabilitdeCroisementEditFieldLabel = uilabel(app.ParamtresPanel);
            app.ProbabilitdeCroisementEditFieldLabel.Text = 'Probabilité de Croisement';
            app.PC = uieditfield(app.ParamtresPanel, 'numeric');
            app.PC.ValueChangedFcn = createCallbackFcn(app, @PCValueChanged, true);
            app.PC.Limits = [0 1];
            app.NombredepopulationEditFieldLabel = uilabel(app.ParamtresPanel);
            app.NombredepopulationEditFieldLabel.Text = 'Nombre de chromosomes';
            app.NPOP = uieditfield(app.ParamtresPanel, 'numeric');
            app.NPOP.ValueChangedFcn = createCallbackFcn(app, @NPOPValueChanged, true);
            app.NombredeVilleEditFieldLabel = uilabel(app.ParamtresPanel);
            app.NombredeVilleEditFieldLabel.Text = 'Taux de sélection';
            app.PS = uieditfield(app.ParamtresPanel, 'numeric');
            app.PS.ValueChangedFcn = createCallbackFcn(app, @PSValueChanged, true);
            app.PS.Limits = [0 1];
            app.NombrediterationEditFieldLabel = uilabel(app.ParamtresPanel);
            app.NombrediterationEditFieldLabel.Text = 'Nombre d''iteration';
            app.NITER = uieditfield(app.ParamtresPanel, 'numeric');
            app.NITER.ValueChangedFcn = createCallbackFcn(app, @NITERValueChanged, true);
            app.OprateursPanel = uipanel(app.TSP_APP);
            app.OprateursPanel.Title = 'Opérateurs';
            app.SlectionDropDownLabel = uilabel(app.OprateursPanel);
            app.SlectionDropDownLabel.Text = 'Sélection';
            app.SelectionOP = uidropdown(app.OprateursPanel);
            app.SelectionOP.Items = {'Roulette', 'Tournoi', 'Rang', ''};
            app.SelectionOP.ValueChangedFcn = createCallbackFcn(app, @SelectionOPValueChanged, true);
            app.SelectionOP.Value = 'Roulette';
            app.CroisementDropDown_2Label = uilabel(app.OprateursPanel);
            app.CroisementDropDown_2Label.Text = 'Croisement';
            app.CroisementOP = uidropdown(app.OprateursPanel);
            app.CroisementOP.Items = {'1-point', 'PMX', 'CX', 'OX'};
            app.CroisementOP.ValueChangedFcn = createCallbackFcn(app, @CroisementOPValueChanged, true);
            app.CroisementOP.Value = '1-point';
            app.MutationDropDownLabel = uilabel(app.OprateursPanel);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.MutationOP = uidropdown(app.OprateursPanel);
            app.MutationOP.Items = {'SWAP', 'RSM', 'PSM'};
            app.MutationOP.ValueChangedFcn = createCallbackFcn(app, @MutationOPValueChanged, true);
            app.MutationOP.Value = 'SWAP';
            app.Elitisme = uicheckbox(app.TSP_APP);
            app.Elitisme.Text = 'Elitisme (recommendé)';
            app.Elitisme.Value = true;
            app.Immigration = uicheckbox(app.TSP_APP);
            app.Immigration.Text = 'Immigration';
            app.RsultatsnumriquesPanel = uipanel(app.TSP_APP);
            app.RsultatsnumriquesPanel.Title = 'Résultats numériques';
            app.chemin = uitextarea(app.RsultatsnumriquesPanel);
            app.chemin.Editable = 'off';
            app.CheminOptimalCourrantLabel = uilabel(app.RsultatsnumriquesPanel);
            app.CheminOptimalCourrantLabel.Text = 'Chemin Optimal Courrant';
            app.MeilleureDsitanceEditFieldLabel = uilabel(app.RsultatsnumriquesPanel);
            app.MeilleureDsitanceEditFieldLabel.Text = 'Meilleure Dsitance';
            app.Distance = uieditfield(app.RsultatsnumriquesPanel, 'text');
            app.Distance.Editable = 'off';
            app.GnrationLabel = uilabel(app.RsultatsnumriquesPanel);
            app.GnrationLabel.Text = 'Génération';
            app.Temps = uieditfield(app.RsultatsnumriquesPanel, 'numeric');
            app.Temps.Editable = 'off';
            app.Exit = uibutton(app.TSP_APP, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tspDesign1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TSP_APP)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSP_APP)
        end
    end
end